classdef Jetson_Live_Updated < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        ConnectionStatusLabel           matlab.ui.control.Label
        ConnectionStatusTextArea        matlab.ui.control.TextArea
        PoseFPSLabel                    matlab.ui.control.Label
        FPS                             matlab.ui.control.EditField
        LivePreviewLamp                 matlab.ui.control.Lamp
        LivePreviewLampLabel            matlab.ui.control.Label
        Detected_Pose                   matlab.ui.container.Panel
        roll                            matlab.ui.control.EditField
        yLabel_5                        matlab.ui.control.Label
        pitch                           matlab.ui.control.EditField
        yLabel_4                        matlab.ui.control.Label
        yaw                             matlab.ui.control.EditField
        yLabel_3                        matlab.ui.control.Label
        EuleranglesZYXdegreesLabel      matlab.ui.control.Label
        ozValue                         matlab.ui.control.EditField
        yLabel_2                        matlab.ui.control.Label
        OrientationquaternionwxyzLabel  matlab.ui.control.Label
        PositionmetersLabel             matlab.ui.control.Label
        oyValue                         matlab.ui.control.EditField
        yLabel                          matlab.ui.control.Label
        oxValue                         matlab.ui.control.EditField
        xLabel                          matlab.ui.control.Label
        owValue                         matlab.ui.control.EditField
        wLabel                          matlab.ui.control.Label
        pzValue                         matlab.ui.control.EditField
        ZLabel                          matlab.ui.control.Label
        pyValue                         matlab.ui.control.EditField
        gyLabel                         matlab.ui.control.Label
        pxValue                         matlab.ui.control.EditField
        XLabel                          matlab.ui.control.Label
        JETSONCAMERALIVESTREAMAPPLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        SHUTDOWNXButton                 matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        STARTButton                     matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        hw              % Jetson hardware connection
        cam             % Camera object
        camName    %Camera name
        camRes        %Camera resolution
        timerObj        % Timer for frame updates
        isInitialized   % Logical flag to track initialization

        isPosePlotActive
        poseFrameHandle
        poseHistory
        maxHistoryPoints

        % ROS2 Objects
        rosNode = []
        statusSubscriber = []
        commandPublisher = []
        poseSubscriber = []   % <<<< NEW: Subscriber for P3P pose data
   
        % Pose estimation data
        latestPose = []       % <<<< NEW: Latest pose from visual estimator
        
        poseConnectionLine = []  % Handle to dotted connection line

        % Pose performance tracking
        poseCount = 0
        poseLastFPSTime = 0
        poseCurrentFPS = 0
        
        % Camera state
        isConnected = false
        isStreaming = false

        % Timer for updates
        updateTimer = []

        % Animation trail line
        trailLine = []

        testTime = 0;  % For test animation
        
        % Initialization flag for plot
        isPlotInitialized = false


        % Performance tracking
        frameCount = 0
        lastFPSTime = 0
        currentFPS = 0


    end
    
    methods (Access = private)
        
        % --- Function to initialize the Jetson connection and camera ---
        function initializeCamera(app)
            try
                % Change lamp to amber (connecting)
                app.LivePreviewLamp.Color = [1 0.4118 0.1608]; % orange
                pause(1);

                app.isInitialized = true;

                % Lamp stays amber after successful init
                app.LivePreviewLamp.Color = [1 0.4118 0.1608];
                disp('Jetson and camera initialized successfully.');

            catch ME
                app.LivePreviewLamp.Color = [1 0 0]; % red
                uialert(app.UIFigure, ...
                    sprintf('Initialization failed:\n%s', ME.message), ...
                    'Initialization Error');
                app.isInitialized = false;
            end
        end


% NEW connectToROS2 function (with pose subscriber added):
function connectToROS2(app)
    try
        % Set ROS2 domain ID (must match Jetson)
        setenv('ROS_DOMAIN_ID', '0');

        % Create ROS2 node
        app.rosNode = ros2node("/matlab_camera_app");

        % Create subscriber for status messages
        app.statusSubscriber = ros2subscriber(...
            app.rosNode, ...
            "/jetson/camera/status", ...
            "std_msgs/String", ...
            @app.statusCallback, ...
            "Reliability","besteffort","Durability","volatile");

        % Create publisher for sending commands
        app.commandPublisher = ros2publisher(...
            app.rosNode, ...
            "/jetson/camera/command", ...
            "std_msgs/String");
        
        % <<<< NEW: Create subscriber for pose data from visual estimator
        app.poseSubscriber = ros2subscriber(...
            app.rosNode, ...
            "/pose_p3p", ...
            "geometry_msgs/PoseStamped", ...
            @app.poseCallback, ...
            "Reliability","besteffort","Durability","volatile");
        
        app.isConnected = true;
        app.LivePreviewLamp.Color = [1 0.4118 0.1608]; % Amber/Orange
        app.ConnectionStatusTextArea.Value = "Connected to ROS2. Camera initialized.";

        if app.isConnected
             app.LivePreviewLamp.Color = [0 1 0]; % Green
        end

        % Log available topics
        app.logAvailableTopics();
    catch ME
        % ... error handling
        app.ConnectionStatusTextArea.Value = sprintf("ROS2 Connection Error: %s", ME.message);
        app.isConnected = false;
        app.LivePreviewLamp.Color = [1 0 0]; % Red
        rethrow(ME);
    end
end

        % Start update timer for UI refresh
        function startUpdateTimer(app)
            % Stop existing timer if any
            app.stopUpdateTimer();

            % Create timer that calls updatePosePlot at 20Hz
            try
                app.updateTimer = timer(...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 0.05, ... % 20 Hz
                    'TimerFcn', @(~,~) app.updatePosePlot(), ...
                    'BusyMode', 'drop', ...
                    'ErrorFcn', @(~,~) app.timerErrorHandler());

                start(app.updateTimer);
                app.isStreaming = true;
                disp('Animation timer started at 20Hz');

                % Reset performance counters
                app.frameCount = 0;
                app.lastFPSTime = tic;

            catch ME
                disp(['Timer creation failed: ' ME.message]);
                app.isStreaming = false;
            end
        end

        function timerErrorHandler(app)
            % Handle timer errors gracefully
            try
                app.stopUpdateTimer();
                pause(0.1);
                if app.isConnected && app.isStreaming
                    app.startUpdateTimer();
                    app.ConnectionStatusTextArea.Value = "Timer restarted automatically.";
                end
            catch
                app.ConnectionStatusTextArea.Value = "Timer error - please restart streaming.";
                app.LivePreviewLamp.Color = [1 0.4118 0.1608]; % Amber
            end
        end


        % Callback for status messages
        function statusCallback(app, msg)
            statusMsg = char(msg.data);
            app.ConnectionStatusTextArea.Value = sprintf("[Jetson] %s", statusMsg);
        end

        % Send command to Jetson
        function sendCommand(app, command)
      
            if app.isConnected && ~isempty(app.commandPublisher)
                msg = ros2message(app.commandPublisher);
                msg.data = command;
                send(app.commandPublisher, msg);
            end
        end

   
        % Callback for pose data from visual estimator
        function poseCallback(app, msg)
            try
                % Increment pose counter for FPS calculation
                app.poseCount = app.poseCount + 1;
        
                % Calculate pose FPS every second
                if app.poseLastFPSTime == 0
                    % Initialize timer on first pose
                    app.poseLastFPSTime = tic;
                else
                    elapsedTime = toc(app.poseLastFPSTime);
                    if elapsedTime >= 1.0
                        app.poseCurrentFPS = app.poseCount / elapsedTime;
                        app.poseCount = 0;
                        app.poseLastFPSTime = tic;
                
                        % Update UI with pose FPS
                        app.FPS.Value = sprintf('%.1f', app.poseCurrentFPS);
                        %app.updateConnectionStatus(sprintf('Pose FPS: %.1f', app.poseCurrentFPS));
                    end
                end


                % Extract position
                px = msg.pose.position.x;
                py = msg.pose.position.y;
                pz = msg.pose.position.z;

                % Extract orientation (quaternion wxyz)
                qw = msg.pose.orientation.w;
                qx = msg.pose.orientation.x;
                qy = msg.pose.orientation.y;
                qz = msg.pose.orientation.z;

                % Store latest pose
                app.latestPose = [px; py; pz; qw; qx; qy; qz];

                % Only update UI if we have a valid pose
                if ~any(isnan(app.latestPose))
                    % --- Update numeric fields ---
                    app.pxValue.Value = sprintf('%.3f', px);
                    app.pyValue.Value = sprintf('%.3f', py);
                    app.pzValue.Value = sprintf('%.3f', pz);
                    app.owValue.Value = sprintf('%.3f', qw);
                    app.oxValue.Value = sprintf('%.3f', qx);
                    app.oyValue.Value = sprintf('%.3f', qy);
                    app.ozValue.Value = sprintf('%.3f', qz);

                    % --- Update Euler angles ---
                    try
                        R = quat2rotm([qw, qx, qy, qz]);
                        eul = rotm2eul(R, 'ZYX');
                        app.yaw.Value   = sprintf('%.1f°', eul(1)*180/pi);
                        app.pitch.Value = sprintf('%.1f°', eul(2)*180/pi);
                        app.roll.Value  = sprintf('%.1f°', eul(3)*180/pi);
                    catch
                        app.yaw.Value = 'N/A';
                        app.pitch.Value = 'N/A';
                        app.roll.Value = 'N/A';
                    end
                end
            catch ME
                app.ConnectionStatusTextArea.Value = sprintf("Pose callback error: %s", ME.message);
            end
        end
  
        
        % Log available ROS2 topics
        function logAvailableTopics(app)
            try
                [topics, types] = ros2('topic', 'list');
                app.ConnectionStatusTextArea.Value = [app.ConnectionStatusTextArea.Value; 
                    sprintf("Found %d ROS2 topics:", numel(topics))];
                
                for i = 1:min(numel(topics), 10) % Show first 10 topics
                    app.ConnectionStatusTextArea.Value = [app.ConnectionStatusTextArea.Value;
                        sprintf("  %s [%s]", topics{i}, types{i})];
                end
            catch
                % Silently fail if topic listing doesn't work
            end
        end

        function initializeAnimationPlot(app)
        % Initialize the 3D animation plot (only runs once until shutdown)
        
        % Check if already initialized - if so, don't reinitialize
        if app.isPlotInitialized
            return;
        end
        
        % Clear the axes completely
        cla(app.UIAxes2);
        
        % Set fixed axis limits
        xlim(app.UIAxes2, [-3 3]);
        ylim(app.UIAxes2, [-3 3]);
        zlim(app.UIAxes2, [-3 3]);
   
        
        % Set axis direction (NED frame convention)
        app.UIAxes2.ZDir = 'reverse';  % Positive Z points down
        app.UIAxes2.YDir = 'reverse';  % Positive Y points right
        
        % Set viewing angle
        view(app.UIAxes2, -43, 25);
        
        % Set axis colors to black
        app.UIAxes2.XColor = [1 1 1];
        app.UIAxes2.YColor = [1 1 1];
        app.UIAxes2.ZColor = [1 1 1];
        app.UIAxes2.GridColor = [0.3 0.3 0.3];
        app.UIAxes2.GridAlpha = 0.5;
        
        % Enable grid and box
        grid(app.UIAxes2, 'on');
        hold(app.UIAxes2,"on");
 
        
        % Keep existing axis labels (they're already set in createComponents)
        % But make them bold and colored
        app.UIAxes2.XLabel.String = 'North (m)';
        app.UIAxes2.XLabel.FontSize = 12;
        app.UIAxes2.XLabel.FontWeight = 'bold';
        app.UIAxes2.XLabel.Color = [1 0 0]; % Red
        
        app.UIAxes2.YLabel.String = 'East (m)';
        app.UIAxes2.YLabel.FontSize = 12;
        app.UIAxes2.YLabel.FontWeight = 'bold';
        app.UIAxes2.YLabel.Color = [0 1 0]; % Green
        
        app.UIAxes2.ZLabel.String = 'Down (m)';
        app.UIAxes2.ZLabel.FontSize = 12;
        app.UIAxes2.ZLabel.FontWeight = 'bold';
        app.UIAxes2.ZLabel.Color = [0 0 1]; % Blue
        
        app.UIAxes2.Title.String = 'Real-time Drone Pose Visualization (NED Frame)';
        app.UIAxes2.Title.FontSize = 14;
        app.UIAxes2.Title.FontWeight = 'bold';
        app.UIAxes2.Title.Color = [1 1 1];
        
        hold(app.UIAxes2, 'on');
        
        % Draw reference frame at origin
        origin = [0, 0, 0];
        scale = 0.5;
        
        % North-axis (+X, Red)
        quiver3(app.UIAxes2, origin(1), origin(2), origin(3), scale, 0, 0, ...
            'r', 'LineWidth', 2, 'MaxHeadSize', 0.5, 'HandleVisibility', 'off');
        
        % East-axis (+Y, Green)
        quiver3(app.UIAxes2, origin(1), origin(2), origin(3), 0, scale, 0, ...
            'g', 'LineWidth', 2, 'MaxHeadSize', 0.5, 'HandleVisibility', 'off');
        
        % Down-axis (+Z, Blue)
        quiver3(app.UIAxes2, origin(1), origin(2), origin(3), 0, 0, scale, ...
            'b', 'LineWidth', 2, 'MaxHeadSize', 0.5, 'HandleVisibility', 'off');
        
        % Add text labels at reference frame
        text(app.UIAxes2, scale+0.1, 0, 0, 'N', 'Color', 'r', ...
            'FontSize', 12, 'FontWeight', 'bold');
        text(app.UIAxes2, 0, scale+0.1, 0, 'E', 'Color', 'g', ...
            'FontSize', 12, 'FontWeight', 'bold');
        text(app.UIAxes2, 0, 0, scale+0.1, 'D', 'Color', 'b', ...
            'FontSize', 12, 'FontWeight', 'bold');
        
        % Origin marker
        plot3(app.UIAxes2, 0, 0, 0, 'ko', 'MarkerSize', 6, 'MarkerFaceColor', 'k');
        
        % Create animated trail line (fresh trail)
        app.trailLine = animatedline(app.UIAxes2, ...
            'LineWidth', 1.5, ...
            'Color', 'black', ...
            'LineStyle', '-', ...
            'HandleVisibility', 'off');
        
        % Initialize empty handles
        app.poseFrameHandle = [];
        app.poseHistory = [];
        
        hold(app.UIAxes2, 'off');
        
        % Mark as initialized
        app.isPlotInitialized = true;
        app.isPosePlotActive = true;

        % app.UIAxes2.DataAspectRatio = [1 1 1];
        % app.UIAxes2.PlotBoxAspectRatio = [1 1 1];
        % 
        % % Prevent MATLAB from resizing figure to accommodate new limits
         app.UIFigure.Resize = 'off';     % Lock figure window size
        % app.UIAxes2.PositionConstraint = 'innerposition'; % Maintain defined pixel box
        
        drawnow;
    end
        % NEW closeApp function (with pose subscriber cleanup):
        function closeApp(app, ~)
            try
                if app.isStreaming
                    app.STOPButtonPushed([], []);
                end
                
                if app.isConnected
                    clear app.commandPublisher  ...
                          app.statusSubscriber app.poseSubscriber app.rosNode;  % <<<< Added poseSubscriber
                    app.isConnected = false;
                end
                
                % Clean up timer
                        if ~isempty(app.updateTimer) && isvalid(app.updateTimer)
                            delete(app.updateTimer);
                        end
            catch
                % ...
            end
            delete(app.UIFigure);
        end
      
        function sendCommandRepeated(app, command, numTimes)
            % Send command multiple times with small delay
            % numTimes: number of times to send (default 5)
            % delay: delay between sends in seconds (default 0.1)

            if nargin < 3
                numTimes = 5;  % Default to 5 times
            end
            delay = 0.1;  % 100ms between sends

            if app.isConnected && ~isempty(app.commandPublisher)
                for i = 1:numTimes
                    try
                        msg = ros2message(app.commandPublisher);
                        msg.data = command;
                        send(app.commandPublisher, msg);
                        fprintf('Sent command "%s" (%d/%d)\n', command, i, numTimes);
                        pause(delay);  % Small delay between sends
                    catch ME
                        fprintf('Error sending command: %s\n', ME.message);
                    end
                end
                app.ConnectionStatusTextArea.Value = sprintf('Sent "%s" %d times', command, numTimes);
            else
                app.ConnectionStatusTextArea.Value = "Not connected to ROS2";
            end
        end

        function updatePosePlot(app)
            % Update the 3D plot with latest pose - Optimized version

            % Guard clauses
            if ~app.isPosePlotActive
                return;
            end

            if isempty(app.latestPose)
                return;
            end

            if ~isvalid(app.UIAxes2)
                return;
            end

            % Extract pose with validation
            try
                px = app.latestPose(1);
                py = app.latestPose(2);
                pz = app.latestPose(3);
                qw = app.latestPose(4);
                qx = app.latestPose(5);
                qy = app.latestPose(6);
                qz = app.latestPose(7);
            catch
                return; % Invalid pose data
            end

            % Skip if pose is invalid
            if any(isnan([px, py, pz, qw, qx, qy, qz])) || ...
                    any(isinf([px, py, pz, qw, qx, qy, qz]))
                return;
            end

            % Convert quaternion to rotation matrix
            try
                % ENU to NED conversion
                R_enu = quat2rotm([qw, qx, qy, qz]);

                % ENU→NED conversion matrix
                R_conv = [0 1 0; 1 0 0; 0 0 -1];

                % Convert to NED frame
                R_ned = R_conv * R_enu;
                p_ned = R_conv * [px; py; pz];

                % NED coordinates
                px_ned = p_ned(1);
                py_ned = p_ned(2);
                pz_ned = p_ned(3);

            catch
                return; % Rotation conversion failed
            end

            % --- CRITICAL: Add point to animated trail line ---
            if ~isempty(app.trailLine) && isvalid(app.trailLine)
                addpoints(app.trailLine, px_ned, py_ned, pz_ned);

                % Limit trail length to prevent memory issues
                if isprop(app, 'maxTrailPoints') && ~isempty(app.maxTrailPoints)
                    % Clear old points if trail gets too long
                    nPoints = get(app.trailLine, 'NumPoints');
                    if nPoints > app.maxTrailPoints
                        tempX = get(app.trailLine, 'XData');
                        tempY = get(app.trailLine, 'YData');
                        tempZ = get(app.trailLine, 'ZData');
                        clearpoints(app.trailLine);
                        addpoints(app.trailLine, ...
                            tempX(end-app.maxTrailPoints+1:end), ...
                            tempY(end-app.maxTrailPoints+1:end), ...
                            tempZ(end-app.maxTrailPoints+1:end));
                    end
                end
            end

            % Store in history (limited to prevent memory issues)
            if isempty(app.poseHistory)
                app.poseHistory = [px_ned, py_ned, pz_ned];
            else
                app.poseHistory = [app.poseHistory; [px_ned, py_ned, pz_ned]];
                maxHistory = 1000; % Limit history size
                if size(app.poseHistory, 1) > maxHistory
                    app.poseHistory = app.poseHistory(end-maxHistory+1:end, :);
                end
            end

            % --- Delete old graphics handles ---
            if ~isempty(app.poseFrameHandle) && all(isvalid(app.poseFrameHandle))
                try
                    delete(app.poseFrameHandle);
                catch
                    % Handle deletion errors
                end
                app.poseFrameHandle = [];
            end

            % --- Draw new graphics ---
            hold(app.UIAxes2, 'on');

            % Current position
            XYZ = [px_ned, py_ned, pz_ned];

            % Body frame axes in NED frame
            O = eye(3);
            O_I = R_ned * O;

            % Scale factors
            arm_scale = 0.5;      % Length of drone arms
            axis_scale = 0.8;     % Length of orientation axes
            extend_scale = 2.0;   % Extended forward line

            % Initialize graphics handles array
            handles = [];

            % 1. Draw orientation axes (RGB = XYZ in body frame)
            h1 = plot3(app.UIAxes2, ...
                [XYZ(1), XYZ(1) + axis_scale * O_I(1,1)], ...
                [XYZ(2), XYZ(2) + axis_scale * O_I(2,1)], ...
                [XYZ(3), XYZ(3) + axis_scale * O_I(3,1)], ...
                'r-', 'LineWidth', 2.0, 'HandleVisibility', 'off');
            handles = [handles; h1];

            h2 = plot3(app.UIAxes2, ...
                [XYZ(1), XYZ(1) + axis_scale * O_I(1,2)], ...
                [XYZ(2), XYZ(2) + axis_scale * O_I(2,2)], ...
                [XYZ(3), XYZ(3) + axis_scale * O_I(3,2)], ...
                'g-', 'LineWidth', 2.0, 'HandleVisibility', 'off');
            handles = [handles; h2];

            h3 = plot3(app.UIAxes2, ...
                [XYZ(1), XYZ(1) + axis_scale * O_I(1,3)], ...
                [XYZ(2), XYZ(2) + axis_scale * O_I(2,3)], ...
                [XYZ(3), XYZ(3) + axis_scale * O_I(3,3)], ...
                'b-', 'LineWidth', 2.0, 'HandleVisibility', 'off');
            handles = [handles; h3];

            % 2. Draw extended forward direction (X-axis extended)
            h4 = plot3(app.UIAxes2, ...
                [XYZ(1), XYZ(1) + extend_scale * O_I(1,1)], ...
                [XYZ(2), XYZ(2) + extend_scale * O_I(2,1)], ...
                [XYZ(3), XYZ(3) + extend_scale * O_I(3,1)], ...
                'b:', 'LineWidth', 1.0, 'HandleVisibility', 'off');
            handles = [handles; h4];

            % 3. Draw quadcopter body frame (X-shape with 4 arms)
            % Arm 1: +X+Y diagonal
            h5 = plot3(app.UIAxes2, ...
                [XYZ(1), XYZ(1) + arm_scale * (O_I(1,1) + O_I(1,2))], ...
                [XYZ(2), XYZ(2) + arm_scale * (O_I(2,1) + O_I(2,2))], ...
                [XYZ(3), XYZ(3) + arm_scale * (O_I(3,1) + O_I(3,2))], ...
                'k-', 'LineWidth', 2.5, 'HandleVisibility', 'off');
            handles = [handles; h5];

            % Arm 2: +X-Y diagonal
            h6 = plot3(app.UIAxes2, ...
                [XYZ(1), XYZ(1) + arm_scale * (O_I(1,1) - O_I(1,2))], ...
                [XYZ(2), XYZ(2) + arm_scale * (O_I(2,1) - O_I(2,2))], ...
                [XYZ(3), XYZ(3) + arm_scale * (O_I(3,1) - O_I(3,2))], ...
                'k-', 'LineWidth', 2.5, 'HandleVisibility', 'off');
            handles = [handles; h6];

            % Arm 3: -X+Y diagonal
            h7 = plot3(app.UIAxes2, ...
                [XYZ(1), XYZ(1) + arm_scale * (-O_I(1,1) + O_I(1,2))], ...
                [XYZ(2), XYZ(2) + arm_scale * (-O_I(2,1) + O_I(2,2))], ...
                [XYZ(3), XYZ(3) + arm_scale * (-O_I(3,1) + O_I(3,2))], ...
                'k-', 'LineWidth', 2.5, 'HandleVisibility', 'off');
            handles = [handles; h7];

            % Arm 4: -X-Y diagonal
            h8 = plot3(app.UIAxes2, ...
                [XYZ(1), XYZ(1) + arm_scale * (-O_I(1,1) - O_I(1,2))], ...
                [XYZ(2), XYZ(2) + arm_scale * (-O_I(2,1) - O_I(2,2))], ...
                [XYZ(3), XYZ(3) + arm_scale * (-O_I(3,1) - O_I(3,2))], ...
                'k-', 'LineWidth', 2.5, 'HandleVisibility', 'off');
            handles = [handles; h8];

            % 4. Draw current position marker
            h9 = scatter3(app.UIAxes2, XYZ(1), XYZ(2), XYZ(3), ...
                60, 'black', 'filled', 'HandleVisibility', 'off');
            handles = [handles; h9];

            % Store handles for next deletion
            app.poseFrameHandle = handles;

            hold(app.UIAxes2, 'off');

            % Update title with current pose (optional - comment out if too slow)
            % title_str = sprintf('Pos: [%.2f, %.2f, %.2f] m', px_ned, py_ned, pz_ned);
            % app.UIAxes2.Title.String = title_str;

            % Limit drawnow rate
            persistent lastDrawTime
            if isempty(lastDrawTime)
                lastDrawTime = tic;
            end

            if toc(lastDrawTime) > 0.033 % ~30 Hz max
                drawnow limitrate;
                lastDrawTime = tic;
            end
        end

    function stopUpdateTimer(app)
        % Safely stop and delete timer
        if ~isempty(app.updateTimer) && isvalid(app.updateTimer)
            try
                % Check if timer is running
                if strcmp(get(app.updateTimer, 'Running'), 'on')
                    stop(app.updateTimer);
                end
                delete(app.updateTimer);
            catch ME
                disp(['Timer cleanup: ' ME.message]);
            end
            app.updateTimer = [];
        end
    end

    function testAnimationStep(app)
        % Generate one step of test animation
        try
            % Initialize or increment time
            if isempty(app.testTime)
                app.testTime = 0;
            end
            app.testTime = app.testTime + 0.05;
            
            % Generate circular spiral trajectory
            radius = 1.5;
            px = radius * cos(app.testTime * 0.5);
            py = radius * sin(app.testTime * 0.5);
            pz = -2.0 + 0.3 * sin(app.testTime * 0.3);
            
            % Generate rotation (yaw spinning)
            qw = cos(app.testTime * 0.3);
            qx = 0;
            qy = 0;
            qz = sin(app.testTime * 0.3);
            
            % Update pose
            app.latestPose = [px; py; pz; qw; qx; qy; qz];
            
            % Update plot
            app.updatePosePlot();
            
        catch ME
            % Stop timer on error
            app.stopUpdateTimer();
            app.ConnectionStatusTextArea.Value = sprintf('Test error: %s', ME.message);
        end
    end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            try
                % Stop the stream on Jetson
                if app.isConnected
                    %app.sendCommand('stop');
                    app.sendCommandRepeated('stop', 5);  % Send 5 times
                    app.ConnectionStatusTextArea.Value = "Stop command sent 5 times.";

                end
                
                 % Stop update timer
                 app.stopUpdateTimer();

                % Set streaming to false but keep plot active and initialized
                app.isStreaming = false;
                app.isPosePlotActive = false; % Stop updating but don't clear

                if ~isempty(app.cam)
                    clear app.cam
                    app.cam = [];
                end
                
                app.STARTButton.Enable = 'on';
                app.STOPButton.Enable = 'on';
                app.SHUTDOWNXButton.Enable = "on";

                app.LivePreviewLamp.Color = [1 0.4118 0.1608]; % Amber/Orange
                        
                app.ConnectionStatusTextArea.Value = "Streaming stopped. Ready for new connection.";
                if app.isStreaming
                    disp('Camera stopped successfully.');
                    app.isStreaming = false;
                end
                

            catch ME
                uialert(app.UIFigure, ...
                    sprintf('Error stopping camera:\n%s', ME.message), ...
                    'Stop Error');
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
             try
                
                % STOP ANY EXISTING TIMER FIRST
                app.stopUpdateTimer();

                % Clear existing timer
                if ~isempty(app.updateTimer)
                    delete(app.updateTimer);
                    app.updateTimer = [];
                end

                %FOR TESTING: Comment out ROS2 connection
                if ~app.isConnected
                    app.connectToROS2();
                    if ~app.isConnected
                        app.ConnectionStatusTextArea.Value = "Failed to connect to ROS2 network";
                        error('Failed to connect to ROS2 network');
                    end
                end

                % Initialize animation plot (only if not already initialized)
                app.initializeAnimationPlot();
                app.isPosePlotActive = true;
        
                % If restarting after STOP, create a new trail from current position
                if app.isPlotInitialized && ~isempty(app.trailLine) && isvalid(app.trailLine)
                    % Clear old trail and create fresh one
                    clearpoints(app.trailLine);
                end

                % Send start command to Jetson
                %app.sendCommand('start');
                % Send start command MULTIPLE TIMES
                app.sendCommandRepeated('start', 10);  % Send 5 times
                app.ConnectionStatusTextArea.Value = "Sending start command to Jetson...";

                % Start the update timer
                app.startUpdateTimer();
                
                % Update UI state
                app.isStreaming = true;
               
                app.STARTButton.Enable = 'on';
                app.STOPButton.Enable = 'on';

                % Change lamp to green
                app.LivePreviewLamp.Color = [0 1 0];

            catch ME
                uialert(app.UIFigure, ...
                    sprintf('Failed to start camera:\n%s', ME.message), ...
                    'Camera Error');
                app.LivePreviewLamp.Color = [1 0 0]; % Red
                app.isStreaming = false;
               
            end
        end

        % Callback function
        function INITIALIZEButtonPushed(app, event)
         
        end

        % Button pushed function: SHUTDOWNXButton
        function SHUTDOWNXButtonPushed(app, event)
            if ~isempty(app.updateTimer) && isvalid(app.updateTimer)
                stop(app.updateTimer);
                delete(app.updateTimer);
                app.updateTimer = [];   
            end

            %app.sendCommand('shutdown');
            app.sendCommandRepeated('shutdown', 5);  % Send 5 times
            app.ConnectionStatusTextArea.Value = "Shutting down..";
            

            % Clear the plot completely and reset to original state
            cla(app.UIAxes2);
            
            % Reset to original appearance from createComponents
            app.UIAxes2.Title.String = 'Camera Pose Visualization';
            app.UIAxes2.XLabel.String = 'North (m)';
            app.UIAxes2.YLabel.String = 'East (m)';
            app.UIAxes2.ZLabel.String = 'Down(m)';
            app.UIAxes2.XColor = [1 0 0];
            app.UIAxes2.YColor = [0 1 0];
            app.UIAxes2.ZColor = [0 0 1];
            app.UIAxes2.Color = [1 1 1]; % White background
            grid(app.UIAxes2, 'on');
            
            % Clear handles
            app.trailLine = [];
            app.poseFrameHandle = [];
            app.poseHistory = [];
            
            % Reset flags
            app.isPlotInitialized = false;
            app.isPosePlotActive = false;
            app.isStreaming = false;
            app.isInitialized = false;

            app.LivePreviewLamp.Color = [1 0 0]; % Red
            app.isInitialized = false;

            drawnow;
            app.ConnectionStatusTextArea.Value = "Shutdown complete. Plot cleared.";
            disp('Shutdown complete. Plot cleared.');
                                 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Real-time Drone Pose Visualization (NED Frame)')
            xlabel(app.UIAxes2, 'North (m)')
            ylabel(app.UIAxes2, 'East(m)')
            zlabel(app.UIAxes2, 'Down(n)')
            app.UIAxes2.XLim = [-3 3];
            app.UIAxes2.YLim = [-3 3];
            app.UIAxes2.ZLim = [-3 3];
            app.UIAxes2.YDir = 'reverse';
            app.UIAxes2.ZDir = 'reverse';
            app.Panel = uipanel(app.GridLayout);
            app.STARTButton = uibutton(app.Panel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.Panel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.SHUTDOWNXButton = uibutton(app.Panel, 'push');
            app.SHUTDOWNXButton.ButtonPushedFcn = createCallbackFcn(app, @SHUTDOWNXButtonPushed, true);
            app.SHUTDOWNXButton.Text = 'SHUTDOWN X';
            app.JETSONCAMERALIVESTREAMAPPLabel = uilabel(app.GridLayout);
            app.JETSONCAMERALIVESTREAMAPPLabel.Text = 'JETSON  CAMERA LIVE STREAM APP';
            app.Detected_Pose = uipanel(app.GridLayout);
            app.XLabel = uilabel(app.Detected_Pose);
            app.XLabel.Text = 'X:';
            app.pxValue = uieditfield(app.Detected_Pose, 'text');
            app.gyLabel = uilabel(app.Detected_Pose);
            app.gyLabel.Text = 'Y:';
            app.pyValue = uieditfield(app.Detected_Pose, 'text');
            app.ZLabel = uilabel(app.Detected_Pose);
            app.ZLabel.Text = 'Z:';
            app.pzValue = uieditfield(app.Detected_Pose, 'text');
            app.wLabel = uilabel(app.Detected_Pose);
            app.wLabel.Text = 'w:';
            app.owValue = uieditfield(app.Detected_Pose, 'text');
            app.xLabel = uilabel(app.Detected_Pose);
            app.xLabel.Text = 'x:';
            app.oxValue = uieditfield(app.Detected_Pose, 'text');
            app.yLabel = uilabel(app.Detected_Pose);
            app.yLabel.Text = 'y:';
            app.oyValue = uieditfield(app.Detected_Pose, 'text');
            app.PositionmetersLabel = uilabel(app.Detected_Pose);
            app.PositionmetersLabel.Text = 'Position (meters)';
            app.OrientationquaternionwxyzLabel = uilabel(app.Detected_Pose);
            app.OrientationquaternionwxyzLabel.Text = 'Orientation(quaternion wxyz)';
            app.yLabel_2 = uilabel(app.Detected_Pose);
            app.yLabel_2.Text = 'z:';
            app.ozValue = uieditfield(app.Detected_Pose, 'text');
            app.EuleranglesZYXdegreesLabel = uilabel(app.Detected_Pose);
            app.EuleranglesZYXdegreesLabel.Text = 'Euler angles (ZYX, degrees)';
            app.yLabel_3 = uilabel(app.Detected_Pose);
            app.yLabel_3.Text = 'Yaw:';
            app.yaw = uieditfield(app.Detected_Pose, 'text');
            app.yLabel_4 = uilabel(app.Detected_Pose);
            app.yLabel_4.Text = 'Pitch:';
            app.pitch = uieditfield(app.Detected_Pose, 'text');
            app.yLabel_5 = uilabel(app.Detected_Pose);
            app.yLabel_5.Text = 'Roll:';
            app.roll = uieditfield(app.Detected_Pose, 'text');
            app.LivePreviewLampLabel = uilabel(app.GridLayout);
            app.LivePreviewLampLabel.Text = 'Live Preview';
            app.LivePreviewLamp = uilamp(app.GridLayout);
            app.FPS = uieditfield(app.GridLayout, 'text');
            app.FPS.Value = '0.0';
            app.PoseFPSLabel = uilabel(app.GridLayout);
            app.PoseFPSLabel.Text = 'Pose FPS';
            app.ConnectionStatusTextArea = uitextarea(app.GridLayout);
            app.ConnectionStatusTextArea.Value = {'Initializing app...'; 'Press START to begin.'};
            app.ConnectionStatusLabel = uilabel(app.GridLayout);
            app.ConnectionStatusLabel.Text = 'ConnectionStatus:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Jetson_Live_Updated

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end